classdef BEZIERAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ClearButton                    matlab.ui.control.Button
        ControlPointsEditField_2       matlab.ui.control.EditField
        ControlPointsEditField_2Label  matlab.ui.control.Label
        SelezionailtipodicurvaButtonGroup  matlab.ui.container.ButtonGroup
        BSplineCurveButton             matlab.ui.control.RadioButton
        BezirCurveButton               matlab.ui.control.RadioButton
        GraficaButton                  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        control_points % Description
        nr_cp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficaButton
        function GraficaButtonPushed(app, event)
           if app.BezirCurveButton.Value==true && app.ControlPointsEditField_2.Value~="";
                syms t;
                B = bernsteinMatrix(app.nr_cp-1, t);
                bezierCurve=simplify(B*app.control_points');
                plot(app.UIAxes,app.control_points(1,:),app.control_points(2,:),'xb-');
                hold(app.UIAxes,'on');
                fplot(app.UIAxes,bezierCurve(1),bezierCurve(2),[0,1],'r');
                hold(app.UIAxes,'off');
           elseif app.BSplineCurveButton.Value==true && app.ControlPointsEditField_2.Value~="";
                tpts = [0 app.nr_cp-1];
                tvec = 0:0.01:app.nr_cp-1;
                [q,~,~,pp] = bsplinepolytraj(app.control_points,tpts,tvec);
                plot(app.UIAxes,app.control_points(1,:),app.control_points(2,:),'xb-');
                hold(app.UIAxes,'on');
                plot(app.UIAxes,q(1,:), q(2,:),'y')
                hold(app.UIAxes,'off');
           else
               fig = uifigure;
               uialert(fig,'Inserisci i punti di controllo nel formato [... ; ...]','Control Points non validi');
           end
        end

        % Callback function
        function ControlPointsEditFieldValueChanged(app, event)
            value = app.ControlPointsEditField.Value;
  
        end

        % Value changed function: ControlPointsEditField_2
        function ControlPointsEditField_2ValueChanged(app, event)
            value=app.ControlPointsEditField_2.Value;
            app.control_points=str2num(value);
            app.nr_cp=length(app.control_points);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Curva')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraficaButton = uibutton(app.UIFigure, 'push');
            app.GraficaButton.ButtonPushedFcn = createCallbackFcn(app, @GraficaButtonPushed, true);
            app.GraficaButton.Text = 'Grafica';
            app.SelezionailtipodicurvaButtonGroup = uibuttongroup(app.UIFigure);
            app.SelezionailtipodicurvaButtonGroup.Title = 'Seleziona il tipo di curva';
            app.BezirCurveButton = uiradiobutton(app.SelezionailtipodicurvaButtonGroup);
            app.BezirCurveButton.Text = 'Beziér Curve';
            app.BezirCurveButton.Value = true;
            app.BSplineCurveButton = uiradiobutton(app.SelezionailtipodicurvaButtonGroup);
            app.BSplineCurveButton.Text = 'B-Spline Curve';
            app.ControlPointsEditField_2Label = uilabel(app.UIFigure);
            app.ControlPointsEditField_2Label.Text = 'Control Points';
            app.ControlPointsEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ControlPointsEditField_2.ValueChangedFcn = createCallbackFcn(app, @ControlPointsEditField_2ValueChanged, true);
            app.ControlPointsEditField_2.Placeholder = 'EX: [1 2 3; 1 2 3]';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEZIERAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end